classdef Fiberdirselect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectDirectoriesUIFigure    matlab.ui.Figure
        SelectMATFILEButton          matlab.ui.control.Button
        SelectOutputDirectoryButton  matlab.ui.control.Button
        InputDirLabel                matlab.ui.control.Label
        OutputDirLabel               matlab.ui.control.Label
        DoneButton                   matlab.ui.control.Button
    end


    properties (Access = public)
        analysisType % analysis Type selected by user
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.SelectDirectoriesUIFigure, "center");
        end

        % Button pushed function: SelectMATFILEButton
        function SelectMATFILEButtonPushed(app, event)
            app.SelectDirectoriesUIFigure.Visible = "off";
            [file,path] = uigetfile('*.m');
            app.InputDirLabel.Text = fullfile(path,file);
            drawnow;
            figure(app.SelectDirectoriesUIFigure)
        end

        % Button pushed function: SelectOutputDirectoryButton
        function SelectOutputDirectoryButtonPushed(app, event)
            app.SelectDirectoriesUIFigure.Visible = "off";
            outputFolder = uigetdir;
            app.OutputDirLabel.Text = outputFolder;
            drawnow;
            figure(app.SelectDirectoriesUIFigure)
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if (app.InputDirLabel.Text ~= "") && (app.OutputDirLabel ~= "")
                disp(app.analysisType);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectDirectoriesUIFigure = uifigure('Visible', 'off');
            app.SelectDirectoriesUIFigure.Name = 'Select Directories';
            app.SelectMATFILEButton = uibutton(app.SelectDirectoriesUIFigure, 'push');
            app.SelectMATFILEButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMATFILEButtonPushed, true);
            app.SelectMATFILEButton.Text = 'Select MAT FILE';
            app.SelectOutputDirectoryButton = uibutton(app.SelectDirectoriesUIFigure, 'push');
            app.SelectOutputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputDirectoryButtonPushed, true);
            app.SelectOutputDirectoryButton.Text = 'Select Output Directory';
            app.InputDirLabel = uilabel(app.SelectDirectoriesUIFigure);
            app.InputDirLabel.Text = '';
            app.OutputDirLabel = uilabel(app.SelectDirectoriesUIFigure);
            app.OutputDirLabel.Text = '';
            app.DoneButton = uibutton(app.SelectDirectoriesUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.SelectDirectoriesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fiberdirselect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectDirectoriesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectDirectoriesUIFigure)
        end
    end
end