classdef SIA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        WholeImageTab                matlab.ui.container.Tab
        AdjustSliderLabel            matlab.ui.control.Label
        WholeImageSlider             matlab.ui.control.Slider
        RedChannelTab                matlab.ui.container.Tab
        AdjustSlider_4Label          matlab.ui.control.Label
        RedImageSlider               matlab.ui.control.Slider
        GreenChannelTab              matlab.ui.container.Tab
        AdjustSlider_3Label          matlab.ui.control.Label
        GreenImageSlider             matlab.ui.control.Slider
        BlueChannelTab               matlab.ui.container.Tab
        AdjustSlider_2Label          matlab.ui.control.Label
        BlueImageSlider              matlab.ui.control.Slider
        ResetImageAdjustmentsButton  matlab.ui.control.Button
        NextImageButton              matlab.ui.control.Button
        SaveAllSelectionsButton      matlab.ui.control.Button
        BacktoMainMenuButton         matlab.ui.control.Button
        AutoSelectionsTree           matlab.ui.container.Tree
        AutoSelectionsNode           matlab.ui.container.TreeNode
        ManualSelectionsTree         matlab.ui.container.Tree
        ManualSelectionsNode         matlab.ui.container.TreeNode
        RemoveAutoSelectedButton     matlab.ui.control.Button
        RemoveManualSelectedButton   matlab.ui.control.Button
        ResetAutoSelectionsButton    matlab.ui.control.Button
        NewROIButton                 matlab.ui.control.Button
        SelectPart1Button            matlab.ui.control.Button
        SelectPart2Button            matlab.ui.control.Button
        SelectPart3Button            matlab.ui.control.Button
        DoneButton                   matlab.ui.control.Button
    end

    properties (Access = public)
        matfilepath % Input Directory 
        outputDirectory % Output Directory
        spec %specification for measurement type
        hsv_thresh %min value for hsv thresholding
        code_dir %directory that holds scripts
    end
    
    properties (Access = private)
        
        imageFilePaths;
        originalFiberLengths;
        originalBoundingBoxes;
        
        num_images;
        
        imds % image Datastore cell array
        imgfig % figure for images
        
        curr_img_index % index of current image in datastore
        
        curr_original_img % current original image
        
        
        curr_original_redc % current original red channel
        curr_original_greenc % current original green channel
        curr_original_bluec % current original blue channel


        image_auto_selections % tree of original auto fiber selections
        
        new_roi_created % 1 if new roi has created for new manual selection
        
        one_part_selected %1 if at least one part of a fiber has been 
        % manually selected
        
        num_manual_fibers %number of manual fibers
        
        new_roi  %roi for newly selected fiber
        new_fiber_num %num of newly selected fiber
        new_part1 %part1 of newly selected fiber
        new_part2 %part2 of newly selected fiber
        new_part3 %part3 of newly selected fiber


        curr_img_results %results array of current image
        
        curr_img_AUTO_resetNode; % node for original auto measurements
        
    end
    
    
    methods (Access = private)
        
        function save_selections(app, selectiontree, selectiontype_str)
            
            all_selections = cell(1,4);
            
            fibers = selectiontree;
            
            num_fibers = length(fibers);
            
            for i = 1:num_fibers
                curr_fiber = fibers(i);
                fiberparts = curr_fiber.Children;
                num_parts = length(fiberparts);
   
                if i == 1
                    all_selections{1,1} = curr_fiber.Text;
                else
                    all_selections{end+1,1} = curr_fiber.Text;
                end
   
    
                for j = 1:num_parts
                    curr_part = fiberparts(j);
                    all_selections{end,j+1} = curr_part.NodeData;
                end
    
            end
            
            T = cell2table(all_selections,'VariableNames',{'Fiber #', 'Part 1', 'Part 2', 'Part 3'});
            
            
            writetable(T, strcat(selectiontype_str, " ", num2str(app.curr_img_index), ".csv"));

        end
        
        function results = getPartlen(app,partROI)
            
            
            positions = partROI.Position;
            
            total_len = 0;
            for i = 1:length(positions)-1
                results = total_len + sqrt(((positions(i+1,2)-positions(i,2))^2)+ ((positions(i+1,1)-positions(i,1))^2)); 
            end


        end
        
        function addAutomaticSelections(app)
           
            currIMGFiberLengths = app.originalFiberLengths{app.curr_img_index};
            currIMGFiberLengthsSize = size(currIMGFiberLengths);
            
            currImgBoundingBoxes = app.originalBoundingBoxes{app.curr_img_index};
       
            for i = 1:currIMGFiberLengthsSize(2)
                h = drawrectangle('Position',currImgBoundingBoxes(:,i).', 'Color', 'blue', 'Deletable', false, 'InteractionsAllowed', 'none', 'FaceSelectable', false);
                new_fiber = uitreenode(app.AutoSelectionsNode, 'Text', strcat("Fiber #", num2str(i)), 'NodeData', h);
                rednode = uitreenode(new_fiber, 'Text', strcat("Red Length:  ", num2str(currIMGFiberLengths(1,i))), 'NodeData',currIMGFiberLengths(1,i));
                greennode = uitreenode(new_fiber, 'Text', strcat("Green Length:  ", num2str(currIMGFiberLengths(2,i))), 'NodeData',currIMGFiberLengths(2,i));
                
               
            end

            app.curr_img_AUTO_resetNode = app.AutoSelectionsNode;
          
        end
        
        function saveAutomaticSelections(app)
            fibers = app.AutoSelectionsNode.Children;
            size_fibers = size(fibers);

            auto_fibers_selections = cell(size_fibers(1), 3);

            for i = 1:size_fibers(1)
    
                curr_fiber = fibers(i);
                redLength = curr_fiber.Children(1).NodeData;
                greenLength = curr_fiber.Children(2).NodeData;
    
                auto_fibers_selections{i, 1} = curr_fiber.Text;
                auto_fibers_selections{i, 2} = redLength;
                auto_fibers_selections{i, 3} = greenLength;
    
            end

            T = cell2table(auto_fibers_selections,'VariableNames',{'Fiber #', 'Red', 'Green'});
            
            writetable(T, strcat("AUTOMAITC_IMAGE", " ", num2str(app.curr_img_index), ".csv"));
        
        end
        
        function results = loaddata(app)
            datapath = "/Users/aazamzia/impy/outputtest/outputs.mat";
            importedData = load(datapath);

            loadedImgPaths = importedData.imgPaths;
            loadedfiberLengths = importedData.fiberLengths;
            loadedboundingBoxes = importedData.boundingBoxes;

            lIPSize = size(loadedImgPaths);
            imgPaths = cell(lIPSize(1),1);
            for i=1:lIPSize(1)
                imgPaths{i} = loadedImgPaths(1,:);
            end

            lflSize = size(loadedfiberLengths);
            allRedLengths = loadedfiberLengths(:,:,1);
            allGreenLengths = loadedfiberLengths(:,:,2);

            imgFiberLengths = cell(lflSize(1),1);
            for i = 1:lflSize(1)
    
                imgRedLenghts = allRedLengths(i,:);
                imgGreenLengths = allGreenLengths(i,:);
                imgFiberLength = [imgRedLenghts(:), imgGreenLengths(:)];
                imgFiberLengths{i} = imgFiberLength.';
            end


            lbbSize = size(loadedboundingBoxes);
            xStarts = loadedboundingBoxes(:,:,1);
            xEnds = loadedboundingBoxes(:,:,3);
            yStarts = loadedboundingBoxes(:,:,2);
            yEnds = loadedboundingBoxes(:,:,4);
            imgBoundingBoxes = cell(lflSize(1),1);
            for i = 1:lbbSize(1)
    
                
                imgxStarts = xStarts(i,:);
    
                %imgxEnds = xEnds(i,:);
                imgyStarts = yStarts(i,:);
                imgWidths = abs(xEnds(i,:) - xStarts(i,:));
                imgHeights = abs(yEnds(i,:) - yStarts(i,:));
                %imgyEnds = yEnds(i,:);
                imgBoundingBox = [imgxStarts(:), imgyStarts(:), imgWidths(:), imgHeights(:)];
                imgBoundingBoxes{i} = imgBoundingBox.';
                %{
                imgxStarts = xStarts(i,:);
                imgxEnds = xEnds(i,:);
                imgyStarts = yStarts(i,:);
                imgyEnds = yEnds(i,:);
                imgBoundingBox = [imgxStarts(:), imgxEnds(:), imgyStarts(:), imgyEnds(:)];
                imgBoundingBoxes{i} = imgBoundingBox.';
                %}
    
            end
            assignin("base", "imgBoundingBoxes", imgBoundingBoxes);
            
            results = [imgPaths, imgFiberLengths, imgBoundingBoxes];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, matfilepath, outputdirectory)
            movegui(app.UIFigure, "east");
            
            app.matfilepath = matfilepath;
            app.outputDirectory = outputdirectory;
            
            dataLoadResults = loaddata(app);

            
            app.imageFilePaths = dataLoadResults(:,1);
            app.originalFiberLengths = dataLoadResults(:,2);
            app.originalBoundingBoxes = dataLoadResults(:,3);
           
            sizeIFP = size(app.imageFilePaths);
            app.num_images = sizeIFP(1);
            
                        
            first_img = imread(app.imageFilePaths{1});
            
            app.imgfig = figure;
            imshow(first_img);
            impixelinfo;
            movegui(app.imgfig, "west");
            
          
            app.curr_original_img = first_img;
            app.curr_original_redc = first_img(:,:,1);
            app.curr_original_greenc = first_img(:,:,2);
            app.curr_original_bluec = first_img(:,:,3);
            
            app.curr_img_index = 1;
            
            app.one_part_selected = 0;
            
            app.new_roi_created = 0;
            app.num_manual_fibers = 0;
            
            app.new_roi = 0;
            app.new_fiber_num = 0;
            app.new_part1 = 0;
            app.new_part2 = 0;
            app.new_part3 = 0;


            addAutomaticSelections(app)


        end

        % Value changing function: WholeImageSlider
        function WholeImageSliderValueChanging(app, event)
            changingValue = event.Value-50;
            imshow(cat(3,app.curr_original_redc+changingValue,app.curr_original_greenc+changingValue,app.curr_original_bluec+changingValue));
            app.curr_original_redc = app.curr_original_redc + changingValue;
            app.curr_original_greenc = app.curr_original_greenc + changingValue;
            app.curr_original_bluec = app.curr_original_bluec + changingValue;
            
        end

        % Value changing function: RedImageSlider
        function RedImageSliderValueChanging(app, event)
            changingValue = event.Value-50;
            imshow(cat(3,app.curr_original_redc+changingValue,app.curr_original_greenc,app.curr_original_bluec));
            app.curr_original_redc = app.curr_original_redc + changingValue;
        end

        % Value changing function: GreenImageSlider
        function GreenImageSliderValueChanging(app, event)
            changingValue = event.Value;
            imshow(cat(3,app.curr_original_redc,app.curr_original_greenc+changingValue,app.curr_original_bluec));
            app.curr_original_greenc = app.curr_original_greenc + changingValue;
        end

        % Value changing function: BlueImageSlider
        function BlueImageSliderValueChanging(app, event)
            changingValue = event.Value;
            imshow(cat(3,app.curr_original_redc,app.curr_original_greenc,app.curr_original_bluec+changingValue));
            app.curr_original_bluec = app.curr_original_bluec + changingValue;
        end

        % Button pushed function: ResetImageAdjustmentsButton
        function ResetImageAdjustmentsButtonPushed(app, event)
            imshow(app.curr_original_img)
            app.curr_original_redc = app.curr_original_img(:,:,1);
            app.curr_original_greenc = app.curr_original_img(:,:,2);
            app.curr_original_bluec = app.curr_original_img(:,:,3);
        end

        % Button pushed function: NextImageButton
        function NextImageButtonPushed(app, event)
            

            if app.curr_img_index == length(app.imageFilePaths)
                fmsg = msgbox("No more images. Please save current selections and quit.");
            else
                clf;
                app.curr_original_img = imread(app.imageFilePaths{app.curr_img_index + 1});
                imshow(app.curr_original_img)
                
                app.curr_img_index = app.curr_img_index + 1;
      
                app.curr_original_redc = app.curr_original_img(:,:,1);
                app.curr_original_greenc = app.curr_original_img(:,:,2);
                app.curr_original_bluec = app.curr_original_img(:,:,3);
                
                app.new_roi_created = 0;
                app.num_manual_fibers = 0;
                app.one_part_selected = 0;
                
                app.new_part1 = 0;
                app.new_part2 = 0;
                app.new_part3 = 0;
                
                app.AutoSelectionsNode.Children.delete;
                app.ManualSelectionsNode.Children.delete;
               
                addAutomaticSelections(app);
                
                
            end
        
        end

        % Button pushed function: SaveAllSelectionsButton
        function SaveAllSelectionsButtonPushed(app, event)
            if length(app.AutoSelectionsNode.Children) > 0 
                saveAutomaticSelections(app)
            end
            if length(app.ManualSelectionsNode.Children) > 0
                save_selections(app, app.ManualSelectionsNode.Children, "MANUAL_Image");
            end

        end

        % Button pushed function: RemoveAutoSelectedButton
        function RemoveAutoSelectedButtonPushed(app, event)
            if length(app.AutoSelectionsTree.SelectedNodes) == 1 & app.ManualSelectionsTree.SelectedNodes.Text ~= "Automatically Selected"
                roidata = app.AutoSelectionsTree.SelectedNodes(1).NodeData;
                delete(roidata);
                app.AutoSelectionsTree.SelectedNodes(1).delete;
            end
            
        end

        % Button pushed function: RemoveManualSelectedButton
        function RemoveManualSelectedButtonPushed(app, event)
            if length(app.ManualSelectionsTree.SelectedNodes) == 1 & app.ManualSelectionsTree.SelectedNodes.Text ~= "Manually Selected"
                allRois = app.ManualSelectionsTree.SelectedNodes(1).NodeData;
                for i = 1:length(allRois)
                    if allRois(i) ~= 0
                        delete(allRois(i))
                    end
                end
          
                app.ManualSelectionsTree.SelectedNodes(1).delete;
            end
        end

        % Button pushed function: ResetAutoSelectionsButton
        function ResetAutoSelectionsButtonPushed(app, event)
            fibers = app.AutoSelectionsNode.Children;
            size_fibers = size(fibers);
            for i = 1:size_fibers(1)
                delete(fibers(i).NodeData);
            end
            addAutomaticSelections(app)
        end

        % Button pushed function: NewROIButton
        function NewROIButtonPushed(app, event)
            if app.new_roi_created ~= 1
                app.num_manual_fibers = app.num_manual_fibers + 1;
                label= strcat('Manual fiber ', num2str(app.num_manual_fibers));
                app.new_roi = drawpolygon('Label',label, 'Deletable', false,'InteractionsAllowed', 'none', 'Color', 'red');
                app.new_fiber_num = app.num_manual_fibers;
                app.new_roi_created = 1;
            else
                fnewroi = msgbox("Select parts for the current new fiber before selecting a new one, and select DONE after.");
            end
        end

        % Button pushed function: SelectPart1Button
        function SelectPart1ButtonPushed(app, event)
            if app.new_roi_created == 1
                app.new_part1 = drawpolyline('Label', "Part 1", 'Deletable', false,'InteractionsAllowed', 'none', 'Color', 'black');
                app.one_part_selected = 1;
            else
                fmsg = msgbox("Create a New ROI before selecting any part");
            end
        end

        % Button pushed function: SelectPart2Button
        function SelectPart2ButtonPushed(app, event)
            if app.new_roi_created == 1
                app.new_part2 = drawpolyline('Label', "Part 2", 'Deletable', false,'InteractionsAllowed', 'none', 'Color', 'black');
                app.one_part_selected = 1;
            else
                fmsg = msgbox("Create a New ROI before selecting any part");
            end
        end

        % Button pushed function: SelectPart3Button
        function SelectPart3ButtonPushed(app, event)
            if app.new_roi_created == 1
                app.new_part3 = drawpolyline('Label', "Part 3", 'Deletable', false,'InteractionsAllowed', 'none', 'Color', 'black');
                app.one_part_selected = 1;
            else
                fmsg = msgbox("Create a New ROI before selecting any part");
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if app.new_roi_created == 1
                
                if app.one_part_selected == 1
                    
                    len_part1 = 0;
                    len_part2 = 0;
                    len_part3 = 0;
                    
                    new_fiber = uitreenode(app.ManualSelectionsNode, 'Text', strcat("Fiber ", num2str(app.new_fiber_num)), 'NodeData',[app.new_roi, app.new_part1, app.new_part2, app.new_part3]);

                    
                    if app.new_part1 ~= 0
                        len_part1 = getPartlen(app,app.new_part1);
                        part1_node = uitreenode(new_fiber, 'Text',num2str(uint32(len_part1)),"NodeData",len_part1);
                    end
                    
                    if app.new_part2 ~= 0
                        len_part2 = getPartlen(app,app.new_part2);
                        part2_node = uitreenode(new_fiber, 'Text',num2str(uint32(len_part2)),"NodeData",len_part2);
                    end
                    
                    if app.new_part3 ~= 0
                        len_part3 = getPartlen(app,app.new_part3);
                        part3_node = uitreenode(new_fiber, 'Text',num2str(uint32(len_part3)),"NodeData",len_part3);
                    end
                    
                    app.new_roi_created = 0;
                    app.one_part_selected = 0;
                    app.new_part1 = 0;
                    app.new_part2 = 0;
                    app.new_part3 = 0;


                else
                    fmsg = msgbox("Select at least one part");
                end
                
            else
                fmsg = msgbox("Create a New ROI");
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WholeImageTab = uitab(app.TabGroup);
            app.WholeImageTab.Title = 'Whole Image';
            app.AdjustSliderLabel = uilabel(app.WholeImageTab);
            app.AdjustSliderLabel.Text = 'Adjust';
            app.WholeImageSlider = uislider(app.WholeImageTab);
            app.WholeImageSlider.ValueChangingFcn = createCallbackFcn(app, @WholeImageSliderValueChanging, true);
            app.WholeImageSlider.Value = 50;
            app.RedChannelTab = uitab(app.TabGroup);
            app.RedChannelTab.Title = 'Red Channel';
            app.AdjustSlider_4Label = uilabel(app.RedChannelTab);
            app.AdjustSlider_4Label.Text = 'Adjust';
            app.RedImageSlider = uislider(app.RedChannelTab);
            app.RedImageSlider.ValueChangingFcn = createCallbackFcn(app, @RedImageSliderValueChanging, true);
            app.RedImageSlider.Value = 50;
            app.GreenChannelTab = uitab(app.TabGroup);
            app.GreenChannelTab.Title = 'Green Channel';
            app.AdjustSlider_3Label = uilabel(app.GreenChannelTab);
            app.AdjustSlider_3Label.Text = 'Adjust';
            app.GreenImageSlider = uislider(app.GreenChannelTab);
            app.GreenImageSlider.ValueChangingFcn = createCallbackFcn(app, @GreenImageSliderValueChanging, true);
            app.GreenImageSlider.Value = 50;
            app.BlueChannelTab = uitab(app.TabGroup);
            app.BlueChannelTab.Title = 'Blue Channel';
            app.AdjustSlider_2Label = uilabel(app.BlueChannelTab);
            app.AdjustSlider_2Label.Text = 'Adjust';
            app.BlueImageSlider = uislider(app.BlueChannelTab);
            app.BlueImageSlider.ValueChangingFcn = createCallbackFcn(app, @BlueImageSliderValueChanging, true);
            app.BlueImageSlider.Value = 50;
            app.ResetImageAdjustmentsButton = uibutton(app.UIFigure, 'push');
            app.ResetImageAdjustmentsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetImageAdjustmentsButtonPushed, true);
            app.ResetImageAdjustmentsButton.Text = 'Reset Image Adjustments';
            app.NextImageButton = uibutton(app.UIFigure, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextImageButtonPushed, true);
            app.NextImageButton.Text = 'Next Image';
            app.SaveAllSelectionsButton = uibutton(app.UIFigure, 'push');
            app.SaveAllSelectionsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAllSelectionsButtonPushed, true);
            app.SaveAllSelectionsButton.Text = 'Save All Selections';
            app.BacktoMainMenuButton = uibutton(app.UIFigure, 'push');
            app.BacktoMainMenuButton.Text = {'Back to '; 'Main Menu'};
            app.AutoSelectionsTree = uitree(app.UIFigure);
            app.AutoSelectionsNode = uitreenode(app.AutoSelectionsTree);
            app.AutoSelectionsNode.Text = 'Automatically Selected';
            app.ManualSelectionsTree = uitree(app.UIFigure);
            app.ManualSelectionsNode = uitreenode(app.ManualSelectionsTree);
            app.ManualSelectionsNode.Text = 'Manually Selected';
            app.RemoveAutoSelectedButton = uibutton(app.UIFigure, 'push');
            app.RemoveAutoSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAutoSelectedButtonPushed, true);
            app.RemoveAutoSelectedButton.Text = 'Remove Selected';
            app.RemoveManualSelectedButton = uibutton(app.UIFigure, 'push');
            app.RemoveManualSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveManualSelectedButtonPushed, true);
            app.RemoveManualSelectedButton.Text = 'Remove Selected';
            app.ResetAutoSelectionsButton = uibutton(app.UIFigure, 'push');
            app.ResetAutoSelectionsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAutoSelectionsButtonPushed, true);
            app.ResetAutoSelectionsButton.Text = 'Reset';
            app.NewROIButton = uibutton(app.UIFigure, 'push');
            app.NewROIButton.ButtonPushedFcn = createCallbackFcn(app, @NewROIButtonPushed, true);
            app.NewROIButton.Text = 'New ROI';
            app.SelectPart1Button = uibutton(app.UIFigure, 'push');
            app.SelectPart1Button.ButtonPushedFcn = createCallbackFcn(app, @SelectPart1ButtonPushed, true);
            app.SelectPart1Button.Text = 'Select Part 1';
            app.SelectPart2Button = uibutton(app.UIFigure, 'push');
            app.SelectPart2Button.ButtonPushedFcn = createCallbackFcn(app, @SelectPart2ButtonPushed, true);
            app.SelectPart2Button.Text = 'Select Part 2';
            app.SelectPart3Button = uibutton(app.UIFigure, 'push');
            app.SelectPart3Button.ButtonPushedFcn = createCallbackFcn(app, @SelectPart3ButtonPushed, true);
            app.SelectPart3Button.Text = 'Select Part 3';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SIA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end